classdef app_advancedFeature < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdvancedFeatureUIFigure    matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        InternalLineHyperlink      matlab.ui.control.Hyperlink
        DenseHyperlink             matlab.ui.control.Hyperlink
        InternalLineCheckBox       matlab.ui.control.CheckBox
        DenseSegmentationCheckBox  matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        callingApp % The calling app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.AdvancedFeatureUIFigure.Visible = "off";
            movegui(app.AdvancedFeatureUIFigure, "center");
            app.AdvancedFeatureUIFigure.Visible = "on";

            % Store inputs
            app.callingApp = caller;
        end

        % Value changed function: DenseSegmentationCheckBox
        function DenseSegmentationCheckBoxValueChanged(app, event)
            value = app.DenseSegmentationCheckBox.Value;
            
            % Handle incompatibility
            if value
                app.InternalLineCheckBox.Enable = "off";
            else
                app.InternalLineCheckBox.Enable = "on";
            end

            app.callingApp.setDense(value);
        end

        % Value changed function: InternalLineCheckBox
        function InternalLineCheckBoxValueChanged(app, event)
            value = app.InternalLineCheckBox.Value;
            
            % Handle incompatibility
            if value
                app.DenseSegmentationCheckBox.Enable = "off";
            else
                app.DenseSegmentationCheckBox.Enable = "on";
            end

            app.callingApp.setInternalLine(value);
        end

        % Callback function: DenseHyperlink
        function DenseHyperlinkClicked(app, event)
            uiconfirm(app.AdvancedFeatureUIFigure, "Set to true if a dense object is present and you wants more checks to be able to recognize open lines and close dense lines.", "Dense Segmentation", "Options", "OK", "Icon", "info");
        end

        % Callback function: InternalLineHyperlink
        function InternalLineHyperlinkClicked(app, event)
            uiconfirm(app.AdvancedFeatureUIFigure, "If true, the internal line is required (when segmentation of more than one pixel).", "Internal Line", "Options", "OK", "Icon", "info");
        end

        % Close request function: AdvancedFeatureUIFigure
        function AdvancedFeatureUIFigureCloseRequest(app, event)
            app.callingApp.enableAdvancedFeatures();
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdvancedFeatureUIFigure = uifigure('Visible', 'off');
            app.AdvancedFeatureUIFigure.Name = 'Advanced Feature';
            app.AdvancedFeatureUIFigure.CloseRequestFcn = createCallbackFcn(app, @AdvancedFeatureUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.AdvancedFeatureUIFigure);
            app.DenseSegmentationCheckBox = uicheckbox(app.GridLayout);
            app.DenseSegmentationCheckBox.ValueChangedFcn = createCallbackFcn(app, @DenseSegmentationCheckBoxValueChanged, true);
            app.DenseSegmentationCheckBox.Text = 'Dense Segmentation';
            app.InternalLineCheckBox = uicheckbox(app.GridLayout);
            app.InternalLineCheckBox.ValueChangedFcn = createCallbackFcn(app, @InternalLineCheckBoxValueChanged, true);
            app.InternalLineCheckBox.Text = 'Internal Line';
            app.DenseHyperlink = uihyperlink(app.GridLayout);
            app.DenseHyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @DenseHyperlinkClicked, true);
            app.DenseHyperlink.Text = 'See More ...';
            app.InternalLineHyperlink = uihyperlink(app.GridLayout);
            app.InternalLineHyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @InternalLineHyperlinkClicked, true);
            app.InternalLineHyperlink.Text = 'See More ...';
            % Show the figure after all components are created
            app.AdvancedFeatureUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_advancedFeature(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AdvancedFeatureUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdvancedFeatureUIFigure)
        end
    end
end