classdef imageTDSFT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        ImageGridLayout  matlab.ui.container.GridLayout
        Image            matlab.ui.control.Image
        CloseButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingApp % Main app
        imageFullName % File FullName (path/name) of the image to display
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, imageFullName)
            % Center the dialog box
            app.UIFigure.Visible = "off";
            movegui(app.UIFigure, "center");
            app.UIFigure.Visible = "on";

            % Store input params
            app.callingApp = caller;
            app.imageFullName = imageFullName;

            img = imread(app.imageFullName);
            app.Image.ImageSource = img;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageGridLayout = uigridlayout(app.UIFigure);
            app.CloseButton = uibutton(app.ImageGridLayout, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Tag = 'p3_back';
            app.CloseButton.Text = 'Close';
            app.Image = uiimage(app.ImageGridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imageTDSFT(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end